classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        BasicTab                      matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        CalculateButton               matlab.ui.control.Button
        NumberofpointsSpinner_2Label  matlab.ui.control.Label
        NumberofpointsSpinner_2       matlab.ui.control.Spinner
        MonteCarloLabel               matlab.ui.control.Label
        MonteCarloOutput              matlab.ui.control.Label
        CuboidLabel                   matlab.ui.control.Label
        CuboidOutput                  matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        ExtendedTab                   matlab.ui.container.Tab
        CalculatevolumeButton         matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        CalculatedVolumeLabel         matlab.ui.control.Label
        PlotobjectButton              matlab.ui.control.Button
        NumberofpointsSpinnerLabel    matlab.ui.control.Label
        NumberofpointsSpinner         matlab.ui.control.Spinner
        FileDropDownLabel             matlab.ui.control.Label
        FileDropDown                  matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function results = rysujBryle(app, axes)
            x1 = 0;
            x2 = 385;
            x3 = 579;
            x4 = 655;
            
            y1 = 0;
            y2 = 352;
            y3 = 417;
            y4 = 439;
            y5 = 572;
            
            z1 = 0;
            z2 = 191;
            z3 = 210;
            z4 = 273;
            
            % Bryła zielona
            line(axes, [x4 x4], [y1 y4], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x4 x4], [y1 y1], [z1 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x4 x4], [y1 y2], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x4 x4], [y2 y4], [z4 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x4 x4], [y4 y4], [z2 z1], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x1 x1], [y1 y4], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x1], [y1 y1], [z1 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x1], [y1 y2], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x1], [y2 y4], [z4 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x1], [y4 y4], [z2 z1], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x1 x4], [y1 y1], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y1 y1], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y1 y1], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y1 y1], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y1 y1], [z1 z1], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x1 x4], [y1 y1], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y1 y1], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y1 y1], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y1 y1], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y1 y1], [z4 z4], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x1 x4], [y2 y2], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y2 y2], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y2 y2], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y2 y2], [z4 z4], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y2 y2], [z4 z4], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x1 x4], [y4 y4], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y4 y4], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y4 y4], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y4 y4], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y4 y4], [z2 z2], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x1 x4], [y4 y4], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y4 y4], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y4 y4], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y4 y4], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x1 x4], [y4 y4], [z1 z1], 'LineWidth', 2, 'color', 'k');
            
            % Bryła czerwona
            line(axes, [x3 x3], [y4 y5], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x3 x3], [y4 y5], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x3 x3], [y4 y4], [z1 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x3 x3], [y5 y5], [z1 z2], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x2 x2], [y4 y5], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x2], [y4 y5], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x2], [y4 y4], [z1 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x2], [y5 y5], [z1 z2], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x2 x3], [y5 y5], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z1 z1], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z1 z1], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x2 x3], [y5 y5], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z2 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z2 z2], 'LineWidth', 2, 'color', 'k');
            
            % Bryla purpurowa
            line(axes, [x3 x3], [y3 y4], [z3 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x3 x3], [y3 y5], [z3 z3], 'LineWidth', 2, 'color', 'k');
            line(axes, [x3 x3], [y5 y5], [z3 z2], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x2 x2], [y3 y4], [z3 z2], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x2], [y3 y5], [z3 z3], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x2], [y5 y5], [z3 z2], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x2 x3], [y5 y5], [z3 z3], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z3 z3], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z3 z3], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y5 y5], [z3 z3], 'LineWidth', 2, 'color', 'k');
            
            line(axes, [x2 x3], [y3 y3], [z3 z3], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y3 y3], [z3 z3], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y3 y3], [z3 z3], 'LineWidth', 2, 'color', 'k');
            line(axes, [x2 x3], [y3 y3], [z3 z3], 'LineWidth', 2, 'color', 'k');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculatevolumeButton
        function CalculatevolumeButtonPushed(app, event)

            %
            % Obliczanie objętości dowolnego kształtu za pomocą metody Monte Narlo
            %
            % Wykorzystano biblioteki:
            % https://www.mathworks.com/matlabcentral/fileexchange/37856-inpolyhedron-are-points-inside-a-triangulated-volume
            % https://www.mathworks.com/matlabcentral/fileexchange/6678-stlread
            %
            
            % Określenie pliku wejściowego
            filename = string(app.FileDropDown.Value);
            
            % [F,V,N] = STLREAD(FILENAME) also returns the face normal vectors.
            % Wykorzystywane do określenia granic dla Monte carlo
            [F, V, N] = stlread(filename);
            
            % FV = STLREAD(FILENAME) imports triangular faces from the binary STL file
            % indicated by FILENAME, and returns the patch struct FV, with fields 'faces'
            % and 'vertices'.
            % Wykorzystywane do testowania, czy punkt należy do figury.
            FV = stlread(filename); 
            
            % Rysowanie obiektu
            app.UIAxes.cla;
            p = patch(app.UIAxes, 'faces', F, 'vertices', V);
            set(p, 'FaceVertexCData', N);
            set(p, 'facealpha', .4)
            set(p, 'EdgeColor', 'none');
            % light
            view(app.UIAxes, 3)
            
            % Losowanie punktów
            numberOfPoints = app.NumberofpointsSpinner.Value;
            % Okreslenie granicy Monte Carlo
            positionMax = max(V);
            positionMin = min(V);
            widthX = abs(positionMax(1))+abs(positionMin(1));
            widthY = abs(positionMax(2))+abs(positionMin(2));
            widthZ = abs(positionMax(3))+abs(positionMin(3));
            X = (rand(1, numberOfPoints)).*(positionMax(1)*2)-abs(positionMin(1));
            Y = (rand(1, numberOfPoints)).*(positionMax(2)*2)-abs(positionMin(2));
            Z = (rand(1, numberOfPoints)).*(positionMax(3)*2)-abs(positionMin(3));
            
            rectangleVolume = widthX * widthY * widthZ;
            
            X = X(:);
            Y = Y(:);
            Z = Z(:);
            
            Points = [X Y Z];
            
            % Sprawdzanie które punkty są wewnątrz wielościanu
            Inside = inpolyhedron(FV, Points);
            
            % Rysowanie punktów
            hold(app.UIAxes);
            plot3(app.UIAxes, Points(~Inside, 1), Points(~Inside, 2), Points(~Inside, 3), 'or');
            plot3(app.UIAxes, Points(Inside, 1), Points(Inside, 2), Points(Inside, 3), 'ob');
            hold(app.UIAxes, 'off');
            
            % Obliczenie objętości
            proportionInside = size(find(Inside==1), 1)/numberOfPoints;
            volume = proportionInside * rectangleVolume;
            volumeLabel = sprintf('Calculated volume: %f', volume);
                        
            display(volumeLabel)
            app.CalculatedVolumeLabel.Text = volumeLabel;
        end

        % Button pushed function: PlotobjectButton
        function PlotobjectButtonPushed(app, event)
            % Określenie pliku wejściowego
            filename = string(app.FileDropDown.Value);
            
            % [F,V,N] = STLREAD(FILENAME) also returns the face normal vectors.
            % Wykorzystywane do określenia granic dla Monte carlo
            [F, V, N] = stlread(filename);
            
            % FV = STLREAD(FILENAME) imports triangular faces from the binary STL file
            % indicated by FILENAME, and returns the patch struct FV, with fields 'faces'
            % and 'vertices'.
            % Wykorzystywane do testowania, czy punkt należy do figury.
            FV = stlread(filename); 
            
            % Rysowanie obiektu
            app.UIAxes.cla;
            p = patch(app.UIAxes, 'faces', F, 'vertices', V);
            set(p, 'FaceVertexCData', N);
            set(p, 'facealpha', .4)
            set(p, 'EdgeColor', 'none');
            % light
            view(app.UIAxes, 3)
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.UIAxes2.cla;
            app.UIAxes3.cla;
            app.UIAxes4.cla;
            
            % Zdefiniowanie punktow bryły
            x1 = 0;
            x2 = 385;
            x3 = 579;
            x4 = 655;
            
            y1 = 0;
            y2 = 352;
            y3 = 417;
            y4 = 439;
            y5 = 572;
            
            z1 = 0;
            z2 = 191;
            z3 = 210;
            z4 = 273;
            
            % Rysowanie bryly
            rysujBryle(app, app.UIAxes2);
            hold(app.UIAxes2)
            
            %
            % Obliczenie objętości klasycznie
            %
            
            % Trapez zielony
            szerokoscTrapezu = x4;
            h = z4;
            a = y2;
            b = y4;
            ob1 = (((a+b)/2)*h)*szerokoscTrapezu;
            
            % Trapez czerwony
            szerokoscTrapezu = x3-x2;
            h = z2;
            a = y5 - y4;
            b = a;
            ob2 = (((a+b)/2)*h)*szerokoscTrapezu;
            
            % Trapez purpurowy
            szerokoscTrapezu = x3-x2;
            h = z3 - z2;
            a = y5 - y3;
            b = y5 - y4;
            ob3 = (((a+b)/2) * h) * szerokoscTrapezu;
            
            % Wynik
            Ob = ob1 + ob2 + ob3;
            disp(fprintf('Kubatura sali wynosi: %d', Ob))
            view(app.UIAxes2, 140, 35)
            
            %
            % TODO obliczenie metodą prostokątów
            %
            rysujBryle(app, app.UIAxes4);
            hold(app.UIAxes4)
            
            x1 = 0;
            x2 = 385;
            x3 = 579;
            x4 = 655;
            
            y1 = 0;
            y2 = 352;
            y3 = 417;
            y4 = 439;
            y5 = 572;
            
            z1 = 0;
            z2 = 191;
            z3 = 210;
            z4 = 273;
            
            % objetosc
            suma = 0;
            
            % Trapez zielony
            for x = x1 : 25 : x4
                for y = y1 : 25 : y2
                    suma = suma + z4;
                    plot3(app.UIAxes4, [x,x], [y,y], [z1,z4],'g');
                    plot3(app.UIAxes4, [x,x], [y,y], [z1,z4],'r.');
                end
            end
            
            % Trapez zielony
            for x = x1 : 25 : x4
                for y = y1 : 25 : y2
                    suma = suma + z4;
                    plot3(app.UIAxes4, [x,x], [y,y], [z1,z4],'g');
                    plot3(app.UIAxes4, [x,x], [y,y], [z1,z4],'r.');
                end
            end
             
            
            [c, d] = meshgrid([x1,x4], [y2,y4]);
            zz = [z4 z4; z2 z2];
            
            for x = x1 : 25 : x4
                for y = y2 : 25 : y4
                    suma = suma + interp2(c, d, zz, x, y);
                    plot3(app.UIAxes4, [x,x], [y,y], [z1,interp2(c, d, zz, x, y)],'g');
                    plot3(app.UIAxes4, [x,x], [y,y], [z1,interp2(c, d, zz, x, y)],'r.');
                end
            end
                
            % purpurowy
            [c, d] = meshgrid([x2,x3], [y3,y4,y5]);
            zz = [z3 z3; z2 z2; z2 z2];
            for x = x2 : 25 : x3
                for y = y3 : 25 : y5
                    suma = suma + (z3 - interp2(c, d, zz, x, y));
                    plot3(app.UIAxes4, [x,x], [y,y], [interp2(c, d, zz, x, y), z3],'g');
                    plot3(app.UIAxes4, [x,x], [y,y], [interp2(c, d, zz, x, y), z3],'r.');
                end
            end
                
                
            %czerwony
            for x = x2 : 25 : x3
                for y = y4 : 25 : y5
                    suma = suma + z2;
                    plot3(app.UIAxes4, [x,x], [y,y], [z1,z2],'g');
                    plot3(app.UIAxes4, [x,x], [y,y], [z1,z2],'r.');
                end
            end
            
            view(3);
                
            
            cuboidWynik = suma * 25^2;
            app.CuboidOutput.Text = num2str(cuboidWynik);
            view(app.UIAxes4, 140, 35)
            
            %
            % Monte Carlo
            %
            
            % Rysowanie bryły
            rysujBryle(app, app.UIAxes3);
            hold(app.UIAxes3);
            
            % Generowanie punktów losowych
            ilosc_punktow = app.NumberofpointsSpinner_2.Value;
            X = (rand(1, ilosc_punktow))*x4;
            Y = (rand(1, ilosc_punktow))*y5;
            Z = (rand(1, ilosc_punktow))*z4;
            
            ile_trafionych = 0;
            objetosc_przestrzeni = x4 * y5 * z4;
            
            % Określenie czy strzał trafiony
            for i = 1:1:ilosc_punktow
                if Z(i) <= z4 && Y(i) <= y2 && X(i) <= x4
                    plot3(app.UIAxes3, X(i),Y(i),Z(i), '*g');
                    ile_trafionych = ile_trafionych + 1;
                elseif Z(i) <= z3 && Y(i) <= y5 && Y(i) >= y4 && X(i) >= x2 && X(i) <= x3
                    plot3(app.UIAxes3, X(i),Y(i),Z(i), '*g');
                    ile_trafionych = ile_trafionych + 1;
                elseif Z(i) <= interp2([x4 0], [y2 y4], [z4 z4; z2 z2], X(i), Y(i), 'cubic')
                    plot3(app.UIAxes3, X(i),Y(i),Z(i), '*g');
                    ile_trafionych = ile_trafionych + 1;
                else
                    plot3(app.UIAxes3, X(i),Y(i),Z(i), '*r');
                end
            end
            view(app.UIAxes3, 140, 35)
            
            % Obliczanie objętości
            wynik = (ile_trafionych/ilosc_punktow) * objetosc_przestrzeni;
            disp(fprintf('Kubatura sali wynosi: %d', wynik));
            app.MonteCarloOutput.Text = num2str(wynik);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.UIAxes2 = uiaxes(app.BasicTab);
            title(app.UIAxes2, 'Object')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.BasicTab);
            title(app.UIAxes3, 'Monte Carlo')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.CalculateButton = uibutton(app.BasicTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NumberofpointsSpinner_2Label = uilabel(app.BasicTab);
            app.NumberofpointsSpinner_2Label.Text = 'Number of points';
            app.NumberofpointsSpinner_2 = uispinner(app.BasicTab);
            app.NumberofpointsSpinner_2.Step = 100;
            app.NumberofpointsSpinner_2.Limits = [0 Inf];
            app.NumberofpointsSpinner_2.Value = 1000;
            app.MonteCarloLabel = uilabel(app.BasicTab);
            app.MonteCarloLabel.Text = 'Monte Carlo: ';
            app.MonteCarloOutput = uilabel(app.BasicTab);
            app.MonteCarloOutput.Text = '';
            app.CuboidLabel = uilabel(app.BasicTab);
            app.CuboidLabel.Text = 'Cuboid:';
            app.CuboidOutput = uilabel(app.BasicTab);
            app.CuboidOutput.Text = '';
            app.UIAxes4 = uiaxes(app.BasicTab);
            title(app.UIAxes4, 'Cuboid')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.ExtendedTab = uitab(app.TabGroup);
            app.ExtendedTab.Title = 'Extended';
            app.CalculatevolumeButton = uibutton(app.ExtendedTab, 'push');
            app.CalculatevolumeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatevolumeButtonPushed, true);
            app.CalculatevolumeButton.Text = 'Calculate volume';
            app.UIAxes = uiaxes(app.ExtendedTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CalculatedVolumeLabel = uilabel(app.ExtendedTab);
            app.CalculatedVolumeLabel.Text = 'Calculated volume: ';
            app.PlotobjectButton = uibutton(app.ExtendedTab, 'push');
            app.PlotobjectButton.ButtonPushedFcn = createCallbackFcn(app, @PlotobjectButtonPushed, true);
            app.PlotobjectButton.Text = 'Plot object';
            app.NumberofpointsSpinnerLabel = uilabel(app.ExtendedTab);
            app.NumberofpointsSpinnerLabel.Text = 'Number of points';
            app.NumberofpointsSpinner = uispinner(app.ExtendedTab);
            app.NumberofpointsSpinner.Step = 1000;
            app.NumberofpointsSpinner.Limits = [0 Inf];
            app.NumberofpointsSpinner.Value = 5000;
            app.FileDropDownLabel = uilabel(app.ExtendedTab);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.ExtendedTab);
            app.FileDropDown.Items = {'torus.stl', 'elipsoida.stl', 'Krog.STL', 'kula.stl', 'femur.stl'};
            app.FileDropDown.Value = 'torus.stl';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end